classdef lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        DiscretizacionButtonGroup  matlab.ui.container.ButtonGroup
        Button                     matlab.ui.control.RadioButton
        Button_2                   matlab.ui.control.RadioButton
        Panel_2                    matlab.ui.container.Panel
        FrecuenciaEditFieldLabel   matlab.ui.control.Label
        FrecuenciaEditField        matlab.ui.control.NumericEditField
        ValorDCEditFieldLabel      matlab.ui.control.Label
        ValorDCEditField           matlab.ui.control.NumericEditField
        FaseEditFieldLabel         matlab.ui.control.Label
        FaseEditField              matlab.ui.control.NumericEditField
        SealesPanel                matlab.ui.container.Panel
        SenoButton_2               matlab.ui.control.Button
        DientedesierraButton_2     matlab.ui.control.Button
        CuadradaButton_2           matlab.ui.control.Button
        PulsoButton                matlab.ui.control.Button
        RuidoBlancoButton          matlab.ui.control.Button
        Panel_3                    matlab.ui.container.Panel
        PeriodoEditFieldLabel      matlab.ui.control.Label
        PeriodoEditField           matlab.ui.control.NumericEditField
        Panel_4                    matlab.ui.container.Panel
        PeriodoEditField_2Label    matlab.ui.control.Label
        PeriodoEditField_2         matlab.ui.control.NumericEditField
        CiclotilEditFieldLabel     matlab.ui.control.Label
        CiclotilEditField          matlab.ui.control.NumericEditField
        GraficarButton             matlab.ui.control.Button
        Panel_5                    matlab.ui.container.Panel
        ValormaxEditField_3Label   matlab.ui.control.Label
        ValormaxEditField          matlab.ui.control.EditField
        ValorminimoEditFieldLabel  matlab.ui.control.Label
        ValorminimoEditField       matlab.ui.control.EditField
        MediaEditField_2Label      matlab.ui.control.Label
        MediaEditField             matlab.ui.control.EditField
        RMSEditFieldLabel          matlab.ui.control.Label
        RMSEditField               matlab.ui.control.EditField
        MedianaEditFieldLabel      matlab.ui.control.Label
        MedianaEditField           matlab.ui.control.EditField
        AmplitudppEditFieldLabel   matlab.ui.control.Label
        AmplitudppEditField        matlab.ui.control.EditField
        VarianzaEditFieldLabel     matlab.ui.control.Label
        VarianzaEditField          matlab.ui.control.EditField
        FFTButton                  matlab.ui.control.Button
        DiscretizarButton          matlab.ui.control.Button
        Panel_6                    matlab.ui.container.Panel
        AmplitudEditFieldLabel     matlab.ui.control.Label
        AmplitudEditField          matlab.ui.control.NumericEditField
        Panel_7                    matlab.ui.container.Panel
        AnchoEditFieldLabel        matlab.ui.control.Label
        AnchoEditField             matlab.ui.control.NumericEditField
        Panel_8                    matlab.ui.container.Panel
        VarianzaEditField_2Label   matlab.ui.control.Label
        VarianzaEditField_2        matlab.ui.control.NumericEditField
        TEditFieldLabel            matlab.ui.control.Label
        TEditField                 matlab.ui.control.NumericEditField
        sumaButton                 matlab.ui.control.Button
        MButton                    matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            grid(app.UIAxes,'on');
            global funcion
            funcion=3;
            app.Panel_7.Visible='off';
            app.Panel_8.Visible='off';
            app.FFTButton.Enable='on';
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: CuadradaButton_2
        function CuadradaButton_2Pushed(app, event)
            global funcion
            funcion=3;
            app.Panel_3.Visible='off';
            app.Panel_2.Visible='off';
            app.Panel_4.Visible='on';
            app.Panel_6.Visible='on';
            app.Panel_7.Visible='off';
            app.Panel_8.Visible='off';
            app.FFTButton.Enable='on';
            
        end

        % Button pushed function: DientedesierraButton_2
        function DientedesierraButton_2Pushed(app, event)
            global funcion
            funcion=2;
            app.Panel_3.Visible='on';
            app.Panel_2.Visible='off';
            app.Panel_4.Visible='off';
            app.Panel_6.Visible='on';
            app.Panel_7.Visible='off';
            app.Panel_8.Visible='off';
            app.FFTButton.Enable='on';
        end

        % Button pushed function: DiscretizarButton
        function DiscretizarButtonPushed(app, event)
            global funcion senal
            
            % Discretización de la señal análoga
            
            % numero de ciclos
            c=2;
            M=app.TEditField.Value;%Numero de muestras
            S=1; %Tiempo para muestrear
            
            hold on
            
            bit=str2num(get(get(app.DiscretizacionButtonGroup,'SelectedObject'), 'Text'))
            switch funcion
                case 1
                    amplitud=app.AmplitudEditField.Value;
                    frecuencia=app.FrecuenciaEditField.Value;
                    p=1/frecuencia;
                    fase=deg2rad(app.FaseEditField.Value);
                    valordc=app.ValorDCEditField.Value;
                    t=linspace(0,3/frecuencia,1000);
                    y=inline('amplitud*sin(2*pi*frecuencia*x+fase)+valordc','x','amplitud','frecuencia','fase','valordc')
                    xt=y(t,amplitud,frecuencia,fase,valordc);
                    seno=xt;
                    senal=seno;
                    
                    Ts=1/((p*c*M)/S);
                    Fs=1/Ts;
                    nT=0:Ts:p*c;
                    xnT=y(nT,amplitud,frecuencia,fase,valordc);
                    stem(app.UIAxes,nT,xnT)
                    legend('Continuo','Discreto')
                    N=Fs;
                    % Señal de tiempo discreto
                    n=0:N;
                    
                    xn=y(n/Fs,amplitud,frecuencia,fase,valordc);
                    
                    app.ValormaxEditField.Value=mat2str(max(seno));
                    app.MediaEditField.Value=mat2str(mean(seno));
                    app.MedianaEditField.Value=mat2str(median(seno));
                    app.ValorminimoEditField.Value=mat2str(min(seno));
                    app.RMSEditField.Value=mat2str(rms(seno));
                    app.VarianzaEditField.Value=mat2str(var(seno,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(seno)-min(seno)));
                    
                case 2
                    amplitud=app.AmplitudEditField.Value;
                    periodo=app.PeriodoEditField.Value;
                    p=periodo;
                    frecuencia=1/periodo;
                    t=linspace(0,3*periodo,1000);
                    
                    
                    y=inline('amplitud*sawtooth(2*pi*x/periodo)','x','amplitud','periodo');
                    xt=y(t,amplitud,periodo);
                    sierra=xt;
                    senal=sierra;
                    
                    Ts=1/((p*c*M)/S);
                    Fs=1/Ts;
                    nT=0:Ts:p*c;
                    xnT=y(nT,amplitud,periodo);
                    stem(nT,xnT)
                    legend('Continuo','Discreto')
                    N=Fs;
                    % Señal de tiempo discreto
                    n=0:N;
                    
                    xn=y(app.UIAxes,n/Fs,amplitud,periodo);
                    
                    app.ValormaxEditField.Value=mat2str(max(sierra));
                    app.MediaEditField.Value=mat2str(mean(sierra));
                    app.MedianaEditField.Value=mat2str(median(sierra));
                    app.ValorminimoEditField.Value=mat2str(min(sierra));
                    app.RMSEditField.Value=mat2str(rms(sierra));
                    app.VarianzaEditField.Value=mat2str(var(sierra,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(sierra)-min(sierra)));
                    
                case 3
                    amplitud=app.AmplitudEditField.Value;
                    periodo=app.PeriodoEditField_2.Value;
                    p=periodo;
                    frecuencia=1/periodo;
                    cicloutil=app.CiclotilEditField.Value;
                    t=linspace(0,3*periodo,1000);
                    y=inline('amplitud*square((2*pi*x/periodo),cicloutil)','x','amplitud','periodo','cicloutil');
                    xt=y(t,amplitud,periodo,cicloutil);
                    cuadrada=xt;
                    senal=cuadrada;
                    
                    Ts=1/((p*c*M)/S);
                    Fs=1/Ts;
                    nT=0:Ts:p*c;
                    xnT=y(nT,amplitud,cicloutil);
                    stem(app.UIAxes,nT,xnT)
                    legend('Continuo','Discreto')
                    N=Fs;
                    % Señal de tiempo discreto
                    n=0:N;
                    
                    xn=y(n/Fs,amplitud,cicloutil);
                    
                    
                    app.ValormaxEditField.Value=mat2str(max(cuadrada));
                    app.MediaEditField.Value=mat2str(mean(cuadrada));
                    app.MedianaEditField.Value=mat2str(median(cuadrada));
                    app.ValorminimoEditField.Value=mat2str(min(cuadrada));
                    app.RMSEditField.Value=mat2str(rms(cuadrada));
                    app.VarianzaEditField.Value=mat2str(var(cuadrada,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(cuadrada)-min(cuadrada)));
                    
                case 4
                    Ancho=app.AnchoEditField.Value;
                    
                    t=linspace(-Ancho-Ancho*0.1,Ancho+Ancho*0.1,1000);
                    y=inline('rectpuls(x,Ancho)','x','Ancho');
                    xt=y(t,Ancho);
                    Pulso=xt;
                    senal=Pulso;
                    
                    p=Ancho;
                    Ts=1/((p*c*M)/S);
                    Fs=1/Ts;
                    nT=0:Ts:p*c;
                    xnT=y(nT,Ancho);
                    stem(app.UIAxes,nT,xnT)
                    legend('Continuo','Discreto')
                    N=Fs;
                    % Señal de tiempo discreto
                    n=0:N;
                    
                    xn=y(n/Fs,Ancho);
                    
                    app.ValormaxEditField.Value=mat2str(max(Pulso));
                    app.MediaEditField.Value=mat2str(mean(Pulso));
                    app.MedianaEditField.Value=mat2str(median(Pulso));
                    app.ValorminimoEditField.Value=mat2str(min(Pulso));
                    app.RMSEditField.Value=mat2str(rms(Pulso));
                    app.VarianzaEditField.Value=mat2str(var(Pulso,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(Pulso)-min(Pulso)));
                    
                case 5
                    Varianza=app.VarianzaEditField_2.Value;
                    Ancho=app.AnchoEditField.Value;
                    
                    % Genera  Ruido blanco
                    t=linspace(0,Ancho,1000);
                    
                    L = length(t);                    
                    k = randn(1,L);   % ruido no uniforme
                    mean1 = 0.0;    % media
                    xt= mean1 + sqrt(Varianza)*k;   % ruido blanco
                    plot(app.UIAxes,t,xt) 
                    
                    ruido=xt;
                    senal=ruido;
                                      
                    nT=t;
                    xnT=ruido;
                    hold(app.UIAxes)
                    stem(t,xnT)
                   
                    N=t;
                    % Señal de tiempo discreto
                    n=0:N;
                    
                    xn=ruido;
                    
                    app.ValormaxEditField.Value=mat2str(max(ruido));
                    app.MediaEditField.Value=mat2str(mean(ruido));
                    app.MedianaEditField.Value=mat2str(median(ruido));
                    app.ValorminimoEditField.Value=mat2str(min(ruido));
                    app.RMSEditField.Value=mat2str(rms(ruido));
                    app.VarianzaEditField.Value=mat2str(var(ruido,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(ruido)-min(ruido)));
                otherwise
                    disp('other value')
                    
            end


            nTs=n/Fs;
            xnTs=xn;
            %cuantificando la señal de tiempo discreto
            ran=max(xt)-min(xt);%rango de la señal
            nbits=bit;%bits de cuantificacion
            nnivs=(2^nbits)-1;%numero de niveles de cuantificacion
            delta=ran/nnivs;%altura de nivel
            nive=min(xt):delta:max(xt);
            nivem=nive+delta/2;
            [~,N]=size(nTs);
            xnq=zeros(1,N);
            for i=1:N
                j=1;
                while xnTs(i)>nivem(j)
                    j=j+1;
                end
                xnq(i)=nive(j);
            end
           
            figure('Name','Señal discretizada y señal cuantificada')
            stem(nTs,xnq)
            hold on
            stem(nTs,xnTs)
            hold off          
            
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            global funcion senal
            
            switch funcion
                case 1
                    amplitud=app.AmplitudEditField.Value;
                    frecuencia=app.FrecuenciaEditField.Value;
                    fase=deg2rad(app.FaseEditField.Value);
                    valordc=app.ValorDCEditField.Value;
                    t=linspace(0,3/frecuencia,1000);
                    seno=amplitud*sin(2*pi*frecuencia*t+fase)+valordc;
                    senal=seno;
                    plot(app.UIAxes,t,seno);
                    app.ValormaxEditField.Value=mat2str(max(seno));
                    app.MediaEditField.Value=mat2str(mean(seno));
                    app.MedianaEditField.Value=mat2str(median(seno));
                    app.ValorminimoEditField.Value=mat2str(min(seno));
                    app.RMSEditField.Value=mat2str(rms(seno));
                    app.VarianzaEditField.Value=mat2str(var(seno,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(seno)-min(seno)));
                case 2
                    amplitud=app.AmplitudEditField.Value;
                    periodo=app.PeriodoEditField.Value;
                    t=linspace(0,3*periodo,1000);
                    sierra=amplitud*sawtooth(2*pi*t/periodo);
                    senal=sierra;
                    plot(app.UIAxes,t,sierra);
                    app.ValormaxEditField.Value=mat2str(max(sierra));
                    app.MediaEditField.Value=mat2str(mean(sierra));
                    app.MedianaEditField.Value=mat2str(median(sierra));
                    app.ValorminimoEditField.Value=mat2str(min(sierra));
                    app.RMSEditField.Value=mat2str(rms(sierra));
                    app.VarianzaEditField.Value=mat2str(var(sierra,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(sierra)-min(sierra)));
                case 3
                    amplitud=app.AmplitudEditField.Value;
                    periodo=app.PeriodoEditField_2.Value;
                    cicloutil=app.CiclotilEditField.Value;
                    t=linspace(0,3*periodo,1000);
                    cuadrada=amplitud*square((2*pi*t/periodo),cicloutil);
                    senal=cuadrada;
                    plot(app.UIAxes,t,cuadrada);
                    app.ValormaxEditField.Value=mat2str(max(cuadrada));
                    app.MediaEditField.Value=mat2str(mean(cuadrada));
                    app.MedianaEditField.Value=mat2str(median(cuadrada));
                    app.ValorminimoEditField.Value=mat2str(min(cuadrada));
                    app.RMSEditField.Value=mat2str(rms(cuadrada));
                    app.VarianzaEditField.Value=mat2str(var(cuadrada,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(cuadrada)-min(cuadrada)));
                case 4
                    Ancho=app.AnchoEditField.Value;
                    
                    t=linspace(-Ancho-Ancho*0.1,Ancho+Ancho*0.1,1000);
                    Pulso=rectpuls(t,Ancho)
                    senal=Pulso;
                    plot(app.UIAxes,t,Pulso);
                    app.ValormaxEditField.Value=mat2str(max(Pulso));
                    app.MediaEditField.Value=mat2str(mean(Pulso));
                    app.MedianaEditField.Value=mat2str(median(Pulso));
                    app.ValorminimoEditField.Value=mat2str(min(Pulso));
                    app.RMSEditField.Value=mat2str(rms(Pulso));
                    app.VarianzaEditField.Value=mat2str(var(Pulso,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(Pulso)-min(Pulso)));
                case 5
                    
                    Varianza=app.VarianzaEditField_2.Value;
                    Ancho=app.AnchoEditField.Value;
                    
                    % Genera  Ruido blanco
                    t=linspace(0,Ancho,1000);
                    
                    L = length(t);                    
                    k = randn(1,L);   % ruido no uniforme
                    mean1 = 0.0;    % media
                    ruido= mean1 + sqrt(Varianza)*k;   % ruido blanco
                    plot(app.UIAxes,t,ruido)                    
                    senal=ruido;
                    plot(app.UIAxes,t,ruido);
                    app.ValormaxEditField.Value=mat2str(max(ruido));
                    app.MediaEditField.Value=mat2str(mean(ruido));
                    app.MedianaEditField.Value=mat2str(median(ruido));
                    app.ValorminimoEditField.Value=mat2str(min(ruido));
                    app.RMSEditField.Value=mat2str(rms(ruido));
                    app.VarianzaEditField.Value=mat2str(var(ruido,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(ruido)-min(ruido)));
                    
                otherwise
                    disp('other value')
                    
            end


        end

        % Callback function
        function PeriodoEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: PulsoButton
        function PulsoButtonPushed(app, event)
            global funcion
            funcion=4;
            app.Panel_2.Visible='off';
            app.Panel_3.Visible='off';
            app.Panel_4.Visible='off';
            app.Panel_6.Visible='off';
            app.Panel_7.Visible='on';
            app.Panel_8.Visible='off';
            app.FFTButton.Enable='on';            
        end

        % Button pushed function: RuidoBlancoButton
        function RuidoBlancoButtonPushed(app, event)
            global funcion
            funcion=5;
            app.Panel_2.Visible='off';
            app.Panel_3.Visible='off';
            app.Panel_4.Visible='off';
            app.Panel_6.Visible='off';
            app.Panel_7.Visible='on';
            app.Panel_8.Visible='on';
            app.FFTButton.Enable='off';
            
        end

        % Callback function
        function SealesButtonGroupSelectionChanged(app, event)
            
            
        end

        % Button pushed function: SenoButton_2
        function SenoButton_2Pushed(app, event)
            global funcion
            funcion=1;
            app.Panel_2.Visible='on';
            app.Panel_3.Visible='off';
            app.Panel_4.Visible='off';
            app.Panel_6.Visible='on';
            app.Panel_7.Visible='off';
            app.Panel_8.Visible='off';
            app.FFTButton.Enable='on';            
            
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            global senal
            x=senal;
            
            figure
            y = fft(x);                               % Compute DFT of x
            m = abs(y);                               % Magnitude
            p = unwrap(angle(y));                     % Phase
            f = (0:length(y)-1)*100/length(y);        % Frequency vector
            
            subplot(2,1,1)
            plot(f,m)
            title('Magnitude')


            subplot(2,1,2)
            plot(f,p*180/pi)
            title('Phase')


        end

        % Button pushed function: sumaButton
        function sumaButtonPushed(app, event)
            global memo senal
            amplitud=app.AmplitudEditField.Value;
            frecuencia=app.FrecuenciaEditField.Value;
            fase=deg2rad(app.FaseEditField.Value);
            valordc=app.ValorDCEditField.Value;
            t=0:0.01:1;
            senal=amplitud*sin(2*pi*frecuencia*t+fase)+valordc;


            tot=senal+memo;
            senal=tot;
            plot(app.UIAxes,t,tot);
                    app.ValormaxEditField.Value=mat2str(max(tot));
                    app.MediaEditField.Value=mat2str(mean(tot));
                    app.MedianaEditField.Value=mat2str(median(tot));
                    app.ValorminimoEditField.Value=mat2str(min(tot));
                    app.RMSEditField.Value=mat2str(rms(tot));
                    app.VarianzaEditField.Value=mat2str(var(tot,1));
                    app.AmplitudppEditField.Value=mat2str(abs(max(tot)-min(tot)));


        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            global senal memo
            amplitud=app.AmplitudEditField.Value;
            frecuencia=app.FrecuenciaEditField.Value;
            fase=deg2rad(app.FaseEditField.Value);
            valordc=app.ValorDCEditField.Value;
            t=0:0.01:1;
            memo=amplitud*sin(2*pi*frecuencia*t+fase)+valordc;
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Segundos')
            ylabel(app.UIAxes, 'Amplitud')
            app.DiscretizacionButtonGroup = uibuttongroup(app.UIFigure);
            app.DiscretizacionButtonGroup.Title = 'Discretizacion';
            app.Button = uiradiobutton(app.DiscretizacionButtonGroup);
            app.Button.Text = '8 ';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.DiscretizacionButtonGroup);
            app.Button_2.Text = '12';
            app.Panel_2 = uipanel(app.UIFigure);
            app.FrecuenciaEditFieldLabel = uilabel(app.Panel_2);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.Panel_2, 'numeric');
            app.ValorDCEditFieldLabel = uilabel(app.Panel_2);
            app.ValorDCEditFieldLabel.Text = 'Valor DC';
            app.ValorDCEditField = uieditfield(app.Panel_2, 'numeric');
            app.FaseEditFieldLabel = uilabel(app.Panel_2);
            app.FaseEditFieldLabel.Text = 'Fase';
            app.FaseEditField = uieditfield(app.Panel_2, 'numeric');
            app.SealesPanel = uipanel(app.UIFigure);
            app.SealesPanel.Title = 'Señales';
            app.SenoButton_2 = uibutton(app.SealesPanel, 'push');
            app.SenoButton_2.ButtonPushedFcn = createCallbackFcn(app, @SenoButton_2Pushed, true);
            app.SenoButton_2.Text = 'Seno';
            app.DientedesierraButton_2 = uibutton(app.SealesPanel, 'push');
            app.DientedesierraButton_2.ButtonPushedFcn = createCallbackFcn(app, @DientedesierraButton_2Pushed, true);
            app.DientedesierraButton_2.Text = 'Diente de sierra';
            app.CuadradaButton_2 = uibutton(app.SealesPanel, 'push');
            app.CuadradaButton_2.ButtonPushedFcn = createCallbackFcn(app, @CuadradaButton_2Pushed, true);
            app.CuadradaButton_2.Text = 'Cuadrada';
            app.PulsoButton = uibutton(app.SealesPanel, 'push');
            app.PulsoButton.ButtonPushedFcn = createCallbackFcn(app, @PulsoButtonPushed, true);
            app.PulsoButton.Text = 'Pulso';
            app.RuidoBlancoButton = uibutton(app.SealesPanel, 'push');
            app.RuidoBlancoButton.ButtonPushedFcn = createCallbackFcn(app, @RuidoBlancoButtonPushed, true);
            app.RuidoBlancoButton.Text = 'Ruido Blanco';
            app.Panel_3 = uipanel(app.UIFigure);
            app.PeriodoEditFieldLabel = uilabel(app.Panel_3);
            app.PeriodoEditFieldLabel.Text = 'Periodo';
            app.PeriodoEditField = uieditfield(app.Panel_3, 'numeric');
            app.Panel_4 = uipanel(app.UIFigure);
            app.PeriodoEditField_2Label = uilabel(app.Panel_4);
            app.PeriodoEditField_2Label.Text = 'Periodo';
            app.PeriodoEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.PeriodoEditField_2.Limits = [0 Inf];
            app.CiclotilEditFieldLabel = uilabel(app.Panel_4);
            app.CiclotilEditFieldLabel.Text = 'Ciclo útil';
            app.CiclotilEditField = uieditfield(app.Panel_4, 'numeric');
            app.CiclotilEditField.Limits = [0 Inf];
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.Panel_5 = uipanel(app.UIFigure);
            app.ValormaxEditField_3Label = uilabel(app.Panel_5);
            app.ValormaxEditField_3Label.Text = 'Valor max';
            app.ValormaxEditField = uieditfield(app.Panel_5, 'text');
            app.ValorminimoEditFieldLabel = uilabel(app.Panel_5);
            app.ValorminimoEditFieldLabel.Text = 'Valor minimo';
            app.ValorminimoEditField = uieditfield(app.Panel_5, 'text');
            app.MediaEditField_2Label = uilabel(app.Panel_5);
            app.MediaEditField_2Label.Text = 'Media';
            app.MediaEditField = uieditfield(app.Panel_5, 'text');
            app.RMSEditFieldLabel = uilabel(app.Panel_5);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMSEditField = uieditfield(app.Panel_5, 'text');
            app.MedianaEditFieldLabel = uilabel(app.Panel_5);
            app.MedianaEditFieldLabel.Text = 'Mediana';
            app.MedianaEditField = uieditfield(app.Panel_5, 'text');
            app.AmplitudppEditFieldLabel = uilabel(app.Panel_5);
            app.AmplitudppEditFieldLabel.Text = 'Amplitud pp';
            app.AmplitudppEditField = uieditfield(app.Panel_5, 'text');
            app.VarianzaEditFieldLabel = uilabel(app.Panel_5);
            app.VarianzaEditFieldLabel.Text = 'Varianza';
            app.VarianzaEditField = uieditfield(app.Panel_5, 'text');
            app.FFTButton = uibutton(app.Panel_5, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.DiscretizarButton = uibutton(app.UIFigure, 'push');
            app.DiscretizarButton.ButtonPushedFcn = createCallbackFcn(app, @DiscretizarButtonPushed, true);
            app.DiscretizarButton.Text = 'Discretizar';
            app.Panel_6 = uipanel(app.UIFigure);
            app.AmplitudEditFieldLabel = uilabel(app.Panel_6);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.Panel_6, 'numeric');
            app.AmplitudEditField.Limits = [0 Inf];
            app.Panel_7 = uipanel(app.UIFigure);
            app.AnchoEditFieldLabel = uilabel(app.Panel_7);
            app.AnchoEditFieldLabel.Text = 'Ancho';
            app.AnchoEditField = uieditfield(app.Panel_7, 'numeric');
            app.AnchoEditField.Limits = [0 Inf];
            app.Panel_8 = uipanel(app.UIFigure);
            app.VarianzaEditField_2Label = uilabel(app.Panel_8);
            app.VarianzaEditField_2Label.Text = 'Varianza';
            app.VarianzaEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.TEditFieldLabel = uilabel(app.UIFigure);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEditField.Limits = [0 Inf];
            app.sumaButton = uibutton(app.UIFigure, 'push');
            app.sumaButton.ButtonPushedFcn = createCallbackFcn(app, @sumaButtonPushed, true);
            app.sumaButton.Text = 'suma';
            app.MButton = uibutton(app.UIFigure, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M';
        end
    end

    methods (Access = public)

        % Construct app
        function app = lab1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end